classdef jumbf_type_checker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        EditField              matlab.ui.control.EditField
        AnalyzeButton          matlab.ui.control.Button
        UploadButton           matlab.ui.control.Button
        Image_2                matlab.ui.control.Image
        JUMBFTypeCheckerLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dhf; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg');
            if isequal(file,0)
                msgbox('User selected Cancel');
            else
                msgbox(['User selected ', fullfile(path,file)]);
                filename = fullfile(path,file);
                fid = fopen(filename);
                [~, name,fmt]=fileparts(filename);
                data = fread(fid,inf,'*uint8')';%background file 
                fclose(fid);
                datahex=dec2hex(data);
                dhf=[];
                for i=1:length(datahex)
                    dh=datahex(i,:);
                    dhf=[dhf,dh];
                end
                app.dhf=dhf;
                I=imread(filename);
                imshow(I,'Parent',app.UIAxes)
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
             % DosomethingButtonPushed() prepares an embedded waitbar
            % within the uibutton and draws random dots within the
            % axes.

            % Store original button text
            originalButtonText = app.AnalyzeButton.Text;
            % When this callback function ends, return the original button state
            cleanup = onCleanup(@()set(app.AnalyzeButton,'Text',originalButtonText,'Icon',''));
            % Change button text to "Processing"
            app.AnalyzeButton.Text = 'Analyzing...';
            % Put text on top of icon
            app.AnalyzeButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.AnalyzeButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar in the button
            app.AnalyzeButton.Icon = wbar;

            jsdb='16AD91E0A37F11EB9D0D0800200C9A66';%As specified by ISO/IEC 19566-8 
            jp360='785F34B75D4B474CB89F1D99E0E3A8DD';%As specified by ISO/IEC 19566-6
            jpnspb='74B11BBFF21D4EEA98C10BEBF23AEFD3';%As specified by ISO/IEC 19566-4
            jpnsrb='DC28B95FB68A498E80640FCA845D6B0E';%As specified by ISO/IEC 19566-4
            dhf=app.dhf;
            n = length(dhf)-31;
            for j=1:n
                currentProg = min(round((size(wbar,2)-2)*(j/100)),size(wbar,2)-2);
                RGB = app.AnalyzeButton.Icon;
                RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
                RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
                app.AnalyzeButton.Icon = RGB;
                % Pause to slow down animation
                pause(.3)
                if dhf(j:j+31)==jsdb
                    t=1;
                    break
                elseif dhf(j:j+31)==jp360
                    t=2;
                    break
                elseif dhf(j:j+31)==jpnspb
                    t=3;
                    break
                elseif dhf(j:j+31)==jpnsrb
                    t=4;
                    break
                else
                    t=0;
                end

            end
            if t==1
                app.EditField.Value="This file contains JPEG Snack JUMBF Super Box";
            elseif t==2
                app.EditField.Value="This file contains JPEG 360 JUMBF Super Box";
            elseif t==3
                app.EditField.Value="This file contains JPEG Privacy and Security Protection Box";
            elseif t==4
                app.EditField.Value="This file contains JPEG Privacy and Security Replacement Box";
            else
                app.EditField.Value="This is other JPEG File";
            end
            % remove waitbar
      %app.AnalyzeButton.Icon = '';
      % Change button name
      %app.AnalyzeButton.Text = 'Process Data';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.JUMBFTypeCheckerLabel = uilabel(app.Panel);
            app.JUMBFTypeCheckerLabel.Text = 'JUMBF Type Checker';
            app.Image_2 = uiimage(app.Panel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.UploadButton = uibutton(app.Panel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Icon = '56-document-outline.gif';
            app.UploadButton.Text = 'Upload';
            app.AnalyzeButton = uibutton(app.Panel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Icon = fullfile(pathToMLAPP, '212-arrow-1-rounded.gif');
            app.AnalyzeButton.Text = 'Analyze';
            app.EditField = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = jumbf_type_checker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end